classdef QCLSpectroMeter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        HTML                            matlab.ui.control.HTML
        KeyLamp                         matlab.ui.control.Lamp
        KeyLampLabel                    matlab.ui.control.Label
        InterlockLamp                   matlab.ui.control.Lamp
        InterlockLampLabel              matlab.ui.control.Label
        DAQconnLamp                     matlab.ui.control.Lamp
        DAQconnLampLabel                matlab.ui.control.Label
        ConnectedLamp                   matlab.ui.control.Lamp
        ConnectedLampLabel              matlab.ui.control.Label
        ArmlaserSwitch                  matlab.ui.control.Switch
        ArmlaserSwitchLabel             matlab.ui.control.Label
        RedaimSwitch                    matlab.ui.control.Switch
        RedaimSwitchLabel               matlab.ui.control.Label
        QCL2Lamp                        matlab.ui.control.Lamp
        QCL2LampLabel                   matlab.ui.control.Label
        QCL1Lamp                        matlab.ui.control.Lamp
        QCL1LampLabel                   matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        SingleTab                       matlab.ui.container.Tab
        nopulsestorecordEditField       matlab.ui.control.NumericEditField
        nopulsestorecordEditFieldLabel  matlab.ui.control.Label
        StartmeasurementButton          matlab.ui.control.StateButton
        AchievedwavelengthumEditField   matlab.ui.control.NumericEditField
        AchievedwavelengthumEditFieldLabel  matlab.ui.control.Label
        TunewavelengthumEditField       matlab.ui.control.NumericEditField
        TunewavelengthumEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        StepTab                         matlab.ui.container.Tab
        WLstepsizeumEditField           matlab.ui.control.NumericEditField
        WLstepsizeumEditFieldLabel      matlab.ui.control.Label
        RecordspectrumButton            matlab.ui.control.Button
        InttimesEditField               matlab.ui.control.NumericEditField
        InttimesEditFieldLabel          matlab.ui.control.Label
        StopWLumEditField               matlab.ui.control.NumericEditField
        StopWLumEditFieldLabel          matlab.ui.control.Label
        StartWLumEditField              matlab.ui.control.NumericEditField
        StartWLumEditFieldLabel         matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        SweepTab                        matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        TextAreaLabel                   matlab.ui.control.Label
        LasersettingsTab                matlab.ui.container.Tab
        SDKfolderEditField              matlab.ui.control.EditField
        SDKfolderEditFieldLabel         matlab.ui.control.Label
        LaserparametersPanel            matlab.ui.container.Panel
        PulsewidthnsEditField_2         matlab.ui.control.NumericEditField
        PulsewidthnsEditField_2Label    matlab.ui.control.Label
        PulsewidthnsEditField           matlab.ui.control.NumericEditField
        PulsewidthnsEditFieldLabel      matlab.ui.control.Label
        DutyCycleEditField_2            matlab.ui.control.NumericEditField
        DutyCycleEditField_2Label       matlab.ui.control.Label
        PulserateHzEditField_2          matlab.ui.control.NumericEditField
        PulserateHzEditField_2Label     matlab.ui.control.Label
        QCL2Label                       matlab.ui.control.Label
        QCL1Label                       matlab.ui.control.Label
        DutyCycleEditField              matlab.ui.control.NumericEditField
        DutyCycleEditFieldLabel         matlab.ui.control.Label
        PulserateHzEditField            matlab.ui.control.NumericEditField
        PulserateHzEditFieldLabel       matlab.ui.control.Label
        DAQsettingsTab                  matlab.ui.container.Tab
        DAQchannelsettingsPanel         matlab.ui.container.Panel
        SamplefreqHzEditField           matlab.ui.control.NumericEditField
        SamplefreqHzEditFieldLabel      matlab.ui.control.Label
        nSamplesEditField               matlab.ui.control.NumericEditField
        nSamplesEditFieldLabel          matlab.ui.control.Label
        Ch0rangeVEditField              matlab.ui.control.NumericEditField
        Ch0rangeVEditFieldLabel         matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        catConn = 0; %connected to Mircat
        daqConn = 0; %connected to DAQ
        numQCLs = 0;% Number of QCLs inside of the laser
        minWL = [0 5]; % min wavelength of a laser (this should be an array sized by numQCL)
        maxWL = [5 10]; % max wavelength of a laser (this should be an array sized by numQCL)
        lowestWL = 0; %lowest wavelength possible of all QCLs
        highestWL = 10; %highest wavelength possible of all QCLs
        laserFreq = [1E5 1E5]; %frequency of pulse repeats of laser (this should be an array sized by numQCL)
        LaserDutyCycle = [1 1]; %laser duty cycle in % (this should be an array sized by numQCL)
        LaserPulseWidth = [100 100]; %(this should be an array sized by numQCL)
        LaserCurrent = [1000 1000]; %currentin mA for each laser
        tempSetPoint = [19 19]; %temperature setpoint for each laser in Celcius
        mxPulseRate = [1E6 1E6]; %laser max pulseRate
        mxPulseWidth = [10 10]; %laser max pulseWidth
        mxDutyCycle = [20 20]; %laser max duty cycle
        mxCurrent = [1000 1000]; %laser max current in mA
        CW_allowed = [0 0]; %boolean indicating if laser supports CW mode
        maxCWcurrent = [0 0]; %max CW current
        cwFilters = [0 0]; %cw Filter installed
        
        units % units linked to a laser
        hdwf = 0 % the hardware identifier for the DAQ (AD2)
        interLockSts %status of the interlock
        KeySwitchSet %status of the key switch
        DAQchannel = 0; %channel 0 (is first channel on AD2)
        DAQrange = 6; %range of the DAQ channel in V. 
        DAQnSamples = 8000; %number of samples to be acquired in one go after trigger
        DAQsampleFreq = 100E6; %sample freq.
        
    end
    
    methods (Access = private)
        %Function taking care of all details of connecting to MirCat laser
        function ConnectMirCat(app)
            %open SDK library, using the SDK folder as defined in settings
            sdk_folder=app.SDKfolderEditField.Value;
            if(isfolder(sdk_folder))
                success = MIRcatInit(sdk_folder); %Initialize hardware library.
            else
                success = false;
            end
            %Next find the API version
            if(success)
                [major, minor, patch] = MIRcatFindAPI();
                app.StatusTextArea.Value = ['MirCat API version:',num2str(major),'.',num2str(minor),'.',num2str(patch),'.\n'];
            else
                app.StatusTextArea.Value = 'MIRcat SDK library not found, please check path.\n';
            end
            %next, connect to device
            app.catConn = MIRcatConnect();
            if(app.catConn)
                %if connecting is succesful, switch the lamp to green
                app.ConnectedLamp.Color = [0 1 0];
                %find out how many QCLs are present
                app.numQCLs = MIRcatGetNumQCL();
                %and get the wavelength ranges
                for i=1:app.numQCLs
                    [app.minWL(i), app.maxWL(i), app.units(i)] = MIRcatFindQclRange(i);
                    [app.LaserPulseWidth(i),app.LaserPulseWidth(i),app.LaserCurrent(i), app.tempSetPoint(i)] = MIRcatGetLaserSettings(i);
                    %[a,b,c,d] = MIRcatGetLaserSettings(i);
                    [app.mxPulseRate(i),app.mxPulseWidth(i),app.mxDutyCycle(i),app.mxCurrent(i)] = MIRcatGetLaserMaxs(i);
                    %[app.CW_allowed(i),app.maxCWcurrent(i),app.cwFilters(i)] = MIRcatGetCWsettings(i);
                end
                app.lowestWL= min(app.minWL);
                app.highestWL = max(app.maxWL);
                %check interlock status
                app.interLockSts = MIRcatCheckInterlock();
                if(app.interLockSts) app.InterlockLamp.Color = [0 1 0]; else app.InterlockLamp.Color = [1 0 0]; end
                %check key status
                app.KeySwitchSet = MIRcatCheckKey();
                if(app.KeySwitchSet) app.KeyLamp.Color = [0 1 0]; else app.KeyLamp.Color = [1 0 0]; end
                %if both the key and interlock are set, enable the arm buttons
                if(app.interLockSts && app.KeySwitchSet)
                    app.ArmlaserSwitch.Enable = 1;
                    app.RedaimSwitch.Enable = 1;
                end
            else
                %if connection is not succesful, raise an error and set
                %lamp to red.
                app.ConnectedLamp.Color = [1 0 0];
                app.StatusTextArea.Value = ['Connecting to MIRCat failed! Check power, wires, etc.\n'];
            end
        end
        %Function taking care of all details of connecting to the DAQ system
        function ConnectDAQ(app)
            app.hdwf = AD2Init();
            if(app.hdwf == 0)
                app.daqConn = 0;
                app.StatusTextArea.Value = ['Connecting to DAQ failed! Check USB connection, etc.\n'];
                app.DAQconnLamp.Color = [1 0 0];
            else
                app.daqConn = 1;
                app.DAQconnLamp.Color = [0 1 0];
                
            end
        end
        
        function results = updateStatusWindow(app)
            arr = {'MIRCat lasers:',app.numQCLs}
            arr(2,:) = {'----------','----------'};
            j=3;
            for i = 1:app.numQCLs
                arr(j,:) = {'QCL',i};
                j=j+1;
                arr(j,:) = {'Min. WL',app.minWL(i)};
                j=j+1;
                arr(j,:) = {'Max. WL',app.maxWL(i)};
                j=j+1;
                arr(j,:) = {'Pulse freq.',app.laserFreq(i)};
                j=j+1;
                arr(j,:) = {'Pulse Width',app.LaserPulseWidth(i)};
                j=j+1;
                arr(j,:) = {'Duty Cycle',app.LaserDutyCycle(i)};
                j=j+1;
                arr(j,:) = {'Current',app.LaserCurrent(i)};
                j=j+1;
                arr(j,:) = {'Temp. setP.',app.tempSetPoint(i)};
                j=j+1;
                arr(j,:) = {'Max. pulseR.',app.mxPulseRate(i)};
                j=j+1;
                arr(j,:) = {'Max. pulseWidth',app.mxPulseRate(i)};
                j=j+1;
                arr(j,:) = {'Max. D.C.',app.mxDutyCycle(i)};
                j=j+1;
                arr(j,:) = {'Max. Current',app.mxCurrent(i)};
                j=j+1;
                if(app.CW_allowed(i))
                    arr(j,:) = {'CW Filter',app.cwFilters(i)};
                    j=j+1;
                    arr(j,:) = {'Max. CW current',app.CW_allowed(i)};
                    j=j+1;
                end
                arr(j,:) = {'----------','----------'};
                j=j+1;
            end
            htmlCode = generateHTMLrep(arr)
            app.HTML.HTMLSource = htmlCode;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global MIRcatSDK_RET_SUCCESS; %Note, you still need to declare variables global within functions to use this properly.
            global MIRcatSDK_RET_INITIALIZATION_FAILURE;
            global MIRcatSDK_UNITS_MICRONS;
            MIRcatDeclareGlobalVars(); %gives the global variables its proper value
            
            %set lamps to off (black), and set buttons/switches to init values
            app.ConnectedLamp.Color = [0 0 0];
            app.QCL1Lamp.Color = [0 0 0];
            app.QCL2Lamp.Color = [0 0 0];
            app.DAQconnLamp.Color = [0 0 0];
            app.InterlockLamp.Color = [0 0 0];
            app.KeyLamp.Color = [0 0 0];
            app.StartmeasurementButton.Enable = 0;
            app.RecordspectrumButton.Enable = 0;
            app.ArmlaserSwitch.Value = "Off";
            app.RedaimSwitch.Value = "Off";
            app.ArmlaserSwitch.Enable = 0;
            app.RedaimSwitch.Enable = 1; %TEMP change for testing purpose
            
            %try to connect to MIRCat
            ConnectMirCat(app);
            %Try to connect to DAQ (e.g. AD2)
            ConnectDAQ(app);
            
            updateStatusWindow(app);
            %%%%%%%%%%%%%%%%%%
            %
            % Add functionality to periodically get data from each laser using:
            %   MIRcatSDK_GetTecCurrent
            %   MIRcatSDK_GetQCLTemperature
            %   MIRcatSDK_GetQCLOperatingMode
            %   MIRcatSDK_GetQCLTemperatureRange
            %
            %   Still have to decide how to handle:
            %   MIRcatSDK_GetWlTrigParams
            %   MIRcatSDK_GetWlTrigChanParams
            %
            %%%%%%%%%%%%%%%%%%%%%%%%
            
        end

        % Value changed function: RedaimSwitch
        function RedaimSwitchValueChanged(app, event)
            value = app.RedaimSwitch.Value;
            if(strcmpi(value,"On"))
                %disable arm laser button (does not work together)
                app.StatusTextArea.Value = "red aim on";
                app.ArmlaserSwitch.Enable = 0;
                %add code to enable redaim laser
                MIRcatArmRedLaser();
            else
                app.StatusTextArea.Value = "red aim off";
                 %add code to disable redaim laser
                MIRcatDisarmRedLaser();
                %enable arm laser button again (does not work together)
                app.ArmlaserSwitch.Enable = 1;
            end
        end

        % Value changed function: SDKfolderEditField
        function SDKfolderEditFieldValueChanged(app, event)
            ConnectMirCat(app);
            updateStatusWindow(app);
        end

        % Value changed function: StartmeasurementButton
        function StartmeasurementButtonValueChanged(app, event)
            value = app.StartmeasurementButton.Value;
            %Note that this button should only be enabled if the laser is
            %armed. This implies that step 1 to 5 have already happened.
            %This is important, as these steps are obligatory 
            % before calling the InitSingleTune function.
            
            %%%%%%%%%%%%
            %in the future, make sure to disable other recording buttons
            %here to avoid issues (you have to run DisableSingleTune before moving on)
                app.RecordspectrumButton.Enable = 0;
            %%%%%%%%%%%%
            
            
            desired_wv = app.TunewavelengthumEditField.Value;
            if((desired_wv < app.lowestWL) || (desired_wv > app.highestWL))
                app.StatusTextArea.Value = 'Warning: wavelength out of range.';
            else %we have a valid wavelength, so lets continue..
                
                %tune to single wavelength, and store result in achieved WL
                achievedWL = MIRcatInitSingleTune(desired_wv);
                app.AchievedwavelengthumEditField.Value = double(achievedWL);


                if(achievedWL ~= 0)
                    %get which QCL is selected by device
                    qcl = MIRcatGetActiveLaser();
                    %get nr of pulses, and desired wavelength from gui
                    repts = app.nopulsestorecordEditField.Value;
                    t_rec=1/app.DAQsampleFreq*app.DAQnSamples;
                    nPulsesInSample = floor(t_rec*app.laserFreq(qcl));
                    %since we acquire several pulses in one set of samples, we have
                    %to calculate how many daq actions need to be repeated.
                    repts = ceil(repts/nPulsesInSample);
                    
                    data=zeros(1,app.DAQnSamples*repts);
                    timestamps=zeros(1,repts);
                    if(repts<1)
                        repts = 1;
                    end
                    fprintf('Init DAQ\n');
                    %pre-init the acquisition channel
                    AD2initAnalogIn(app.hdwf, app.DAQchannel, app.DAQsampleFreq, app.DAQrange, app.DAQnSamples,0);
                    %Switch emission on
                    fprintf('Emission on!!\n');
                    %MIRcatEmissionOn();
                    
                    %Next, start collecting samples from DAQ
                    j=1;
                    k=1;
                    for i=1:repts
                        fprintf(['Rep. ',num2str(i),'\n']);
                        %start the analog sampling, we do this until the waitTillFinishedIsDone
                        AD2StartAnalogIn(app.hdwf,0,1);
                        %get the data from the analog input channel
                        timestamps(k)=now;
                        tempData = AD2GetAnalogData(app.hdwf, app.DAQchannel, app.DAQnSamples);
                        data(j:j+app.DAQnSamples-1)=tempData;
                        j=j+app.DAQnSamples;
                        k=k+1;
                    end
                    save(strjoin(['raw data ',string(datetime("now","Format","dd-MMM-uuuu_HH-mm-ss")),'.mat']),'data');
                    %data acquisition finished, lets switch off the laser.
                    fprintf('Emission off!!\n');
                    %MIRcatEmissionOff();
                    MIRcatDisableSingleTune(); %important to execute, before moving on
                    
                    timestamps = timestamps * 24 * 60 * 60; %to convert from days to seconds
                    [time,proc_data] = processMirCatData(timestamps,data,app.laserFreq(qcl),app.LaserDutyCycle(qcl),app.DAQsampleFreq,app.DAQnSamples);
                    plot(app.UIAxes,time,proc_data);
                    save(strjoin(['processed data ',string(datetime("now","Format","dd-MMM-uuuu_HH-mm-ss")),'.mat']),'proc_data','time');
                    %%%%%%%%%%%%
                     %in the future, make sure to re-enable other recording buttons
                     %here to avoid issues (you have to run DisableSingleTune before moving on)
                      app.RecordspectrumButton.Enable = 1;
                    %%%%%%%%%%%%
                end
                
            end


        end

        % Value changed function: ArmlaserSwitch
        function ArmlaserSwitchValueChanged(app, event)
            value = app.ArmlaserSwitch.Value;
            updateStatusWindow(app);
            if(strcmpi(value,"On"))
                app.RedaimSwitch.Enable = 0;
                % Step 2: Check for Interlock Status
                app.interLockSts = MIRcatCheckInterlock();
                if(app.interLockSts)
                    app.InterlockLamp.Color = [0 1 0];
                    % Step 3: Check for Key Switch Status
                    app.KeySwitchSet = MIRcatCheckKey();
                    if(app.KeySwitchSet)
                        app.KeyLamp.Color = [0 1 0];
                        % Step 4: Arm the laser & step 5 Wait for TECs to arrive at safe temp
                        isArmed = MIRcatArmLaser();
                        if(~isArmed)
                            app.ArmlaserSwitch.Value = 0;
                            app.StatusTextArea.Value = 'Arming laser failed!';
                        else
                            app.StartmeasurementButton.Enable = 1;
                            app.RecordspectrumButton.Enable = 1;
                        end
                    else
                        app.KeyLamp.Color = [1 0 0];
                    end
                else
                    app.InterlockLamp.Color = [1 0 0];
                end
            else
                app.StatusTextArea.Value = 'disarming laser.';
                MIRcatDisarmLaser();
                app.RedaimSwitch.Enable = 1;
                app.StartmeasurementButton.Enable = 0;
                app.RecordspectrumButton.Enable = 0;
                
            end
        end

        % Value changed function: SamplefreqHzEditField
        function SamplefreqHzEditFieldValueChanged(app, event)
            app.DAQsampleFreq = app.SamplefreqHzEditField.Value;
            
        end

        % Value changed function: nSamplesEditField
        function nSamplesEditFieldValueChanged(app, event)
            app.DAQnSamples = app.nSamplesEditField.Value;
            
        end

        % Value changed function: Ch0rangeVEditField
        function Ch0rangeVEditFieldValueChanged(app, event)
            app.DAQrange = app.Ch0rangeVEditField.Value;
            
        end

        % Value changed function: PulserateHzEditField
        function PulserateHzEditFieldValueChanged(app, event)
            app.laserFreq(1) = app.PulserateHzEditField.Value;
            app.LaserPulseWidth(1) = app.LaserDutyCycle(1)/100/app.laserFreq(1)*1E9;
            app.PulsewidthnsEditField.Value = app.LaserPulseWidth(1);
            updateStatusWindow(app);
             %%%%%%%%%%%%
            %in the future, call the MIRcatSDK_SetQCLParams function
            
            %%%%%%%%%%%%
            
        end

        % Value changed function: DutyCycleEditField
        function DutyCycleEditFieldValueChanged(app, event)
            app.LaserDutyCycle(1) = app.DutyCycleEditField.Value;
            app.LaserPulseWidth(1) = app.LaserDutyCycle(1)/100/app.laserFreq(1)*1E9;
            app.PulsewidthnsEditField.Value = app.LaserPulseWidth(1);
            updateStatusWindow(app);
            %%%%%%%%%%%%
            %in the future, call the MIRcatSDK_SetQCLParams function
            
            %%%%%%%%%%%%
        end

        % Button pushed function: RecordspectrumButton
        function RecordspectrumButtonPushed(app, event)
            %%%%%%%%%%%%
            %in the future, make sure to disable other recording buttons
            %here to avoid issues (you have to run DisableSingleTune before moving on)
                app.StartmeasurementButton.Enable = 0;
            %%%%%%%%%%%%
            %Note that this button should only be enabled if the laser is
            %armed. This implies that step 1 to 5 have already happened.
            %This is important, as these steps are obligatory 
            % before initiating a step-scan.
            startWaveL_um = app.StartWLumEditField.Value;
            stopWaveL_um = app.StopWLumEditField.Value;
            waveL_step = app.WLstepsizeumEditField.Value;
            intTime = app.InttimesEditField.Value;
           
            if(stopWaveL_um>app.maxWL || stopWaveL_um<app.minWL)
                app.StatusTextArea.Value = 'Stop WL out of range!';
            else
                 if(startWaveL_um>app.maxWL || startWaveL_um<app.minWL)
                    app.StatusTextArea.Value = 'Start WL out of range!';
                else
                    %Set the trigger to external, using MIRcatSDK_SetWlTrigParams
                    MIRcatStepMeasureScan(startWaveL_um, stopWaveL_um, waveL_step); %might need mods, to allow while routine below.
                    %while(scanStatus)
                        %obtain current WL and scanStatus using MIRcatSDK_GetScanStatus, and add the WL to an array.
                        %Add routine to trigger a pulse by AD2, to move to next WL after intTime has passed.
                    %end
                 end
            end
            %%%%%%%%%%%%
            %in the future, make sure to re-enable other recording buttons
            %here to avoid issues (you have to run DisableSingleTune before moving on)
                app.StartmeasurementButton.Enable = 1;
            %%%%%%%%%%%%
            
        end

        % Value changed function: DutyCycleEditField_2
        function DutyCycleEditField_2ValueChanged(app, event)
            app.LaserDutyCycle(2) = app.DutyCycleEditField_2.Value;
            app.LaserPulseWidth(2) = app.LaserDutyCycle(2)/100/app.laserFreq(2)*1E9;
            app.PulsewidthnsEditField_2.Value = app.LaserPulseWidth(2);
            updateStatusWindow(app);
             %%%%%%%%%%%%
            %in the future, call the MIRcatSDK_SetQCLParams function
            
            %%%%%%%%%%%%
            
        end

        % Value changed function: PulserateHzEditField_2
        function PulserateHzEditField_2ValueChanged(app, event)
            app.laserFreq(2) = app.PulserateHzEditField_2.Value;
            app.LaserPulseWidth(2) = app.LaserDutyCycle(2)/100/app.laserFreq(2)*1E9;
            app.PulsewidthnsEditField_2.Value = app.LaserPulseWidth(2);
            updateStatusWindow(app);
             %%%%%%%%%%%%
            %in the future, call the MIRcatSDK_SetQCLParams function
            
            %%%%%%%%%%%%
        end

        % Value changed function: PulsewidthnsEditField_2
        function PulsewidthnsTextAreaValueChanged(app, event)
            %removed field, function not removed by app designer
        end

        % Callback function
        function PulsewidthnsTextArea_2ValueChanged(app, event)
            %removed field, function not removed by app designer
        end

        % Value changed function: PulsewidthnsEditField
        function PulsewidthnsEditFieldValueChanged(app, event)
            app.LaserPulseWidth(1) = app.PulsewidthnsEditField.Value;
            app.LaserDutyCycle(1) = app.LaserPulseWidth(1)/1E9*app.laserFreq(1)*100;
            app.DutyCycleEditField.Value = app.LaserDutyCycle(1);
            updateStatusWindow(app);
            %%%%%%%%%%%%
            %in the future, call the MIRcatSDK_SetQCLParams function
            
            %%%%%%%%%%%%
            
        end

        % Callback function
        function PulsewidthnsEditField_2ValueChanged(app, event)
            app.LaserPulseWidth(2) = app.PulsewidthnsEditField_2.Value;
            app.LaserDutyCycle(2) = app.LaserPulseWidth(2)/1E9*app.laserFreq(2)*100;
            app.DutyCycleEditField.Value = app.LaserDutyCycle(2);
            updateStatusWindow(app);
            %%%%%%%%%%%%
            %in the future, call the MIRcatSDK_SetQCLParams function
            
            %%%%%%%%%%%%
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to close the app?', 'Close request');
            if strcmpi(YN,'OK')
                %% disarm, close connection, de-init libs.
                AD2close();
                %MIRcatDisarmLaser is causing a crash of Matlab if there is
                %no connection established. 
                if(app.catConn)
                    MIRcatDisarmLaser();
                end
                MIRcatClose();
                delete(app);
            end
            %delete(app);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {592, 592};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {187, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StatusTextAreaLabel = uilabel(app.LeftPanel);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.LeftPanel);
            app.QCL1LampLabel = uilabel(app.LeftPanel);
            app.QCL1LampLabel.Text = 'QCL1';
            app.QCL1Lamp = uilamp(app.LeftPanel);
            app.QCL2LampLabel = uilabel(app.LeftPanel);
            app.QCL2LampLabel.Text = 'QCL2';
            app.QCL2Lamp = uilamp(app.LeftPanel);
            app.RedaimSwitchLabel = uilabel(app.LeftPanel);
            app.RedaimSwitchLabel.Text = 'Red aim';
            app.RedaimSwitch = uiswitch(app.LeftPanel, 'slider');
            app.RedaimSwitch.ValueChangedFcn = createCallbackFcn(app, @RedaimSwitchValueChanged, true);
            app.ArmlaserSwitchLabel = uilabel(app.LeftPanel);
            app.ArmlaserSwitchLabel.Text = 'Arm laser';
            app.ArmlaserSwitch = uiswitch(app.LeftPanel, 'slider');
            app.ArmlaserSwitch.ValueChangedFcn = createCallbackFcn(app, @ArmlaserSwitchValueChanged, true);
            app.ConnectedLampLabel = uilabel(app.LeftPanel);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.LeftPanel);
            app.DAQconnLampLabel = uilabel(app.LeftPanel);
            app.DAQconnLampLabel.Text = 'DAQ conn.';
            app.DAQconnLamp = uilamp(app.LeftPanel);
            app.InterlockLampLabel = uilabel(app.LeftPanel);
            app.InterlockLampLabel.Text = 'Interlock';
            app.InterlockLamp = uilamp(app.LeftPanel);
            app.KeyLampLabel = uilabel(app.LeftPanel);
            app.KeyLampLabel.Text = 'Key';
            app.KeyLamp = uilamp(app.LeftPanel);
            app.HTML = uihtml(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.SingleTab = uitab(app.TabGroup);
            app.SingleTab.Title = 'Single';
            app.UIAxes = uiaxes(app.SingleTab);
            title(app.UIAxes, 'Intensity MCT vs. time')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Intensity (Vrms)')
            zlabel(app.UIAxes, 'Z')
            app.TunewavelengthumEditFieldLabel = uilabel(app.SingleTab);
            app.TunewavelengthumEditFieldLabel.Text = 'Tune wavelength (um)';
            app.TunewavelengthumEditField = uieditfield(app.SingleTab, 'numeric');
            app.AchievedwavelengthumEditFieldLabel = uilabel(app.SingleTab);
            app.AchievedwavelengthumEditFieldLabel.Text = 'Achieved wavelength (um)';
            app.AchievedwavelengthumEditField = uieditfield(app.SingleTab, 'numeric');
            app.AchievedwavelengthumEditField.Editable = 'off';
            app.StartmeasurementButton = uibutton(app.SingleTab, 'state');
            app.StartmeasurementButton.ValueChangedFcn = createCallbackFcn(app, @StartmeasurementButtonValueChanged, true);
            app.StartmeasurementButton.Text = 'Start measurement';
            app.nopulsestorecordEditFieldLabel = uilabel(app.SingleTab);
            app.nopulsestorecordEditFieldLabel.Text = 'no. pulses to record';
            app.nopulsestorecordEditField = uieditfield(app.SingleTab, 'numeric');
            app.nopulsestorecordEditField.Value = 10;
            app.StepTab = uitab(app.TabGroup);
            app.StepTab.Title = 'Step';
            app.UIAxes2 = uiaxes(app.StepTab);
            title(app.UIAxes2, 'Intensity vs. wavenumber')
            xlabel(app.UIAxes2, 'Wavelength (um)')
            ylabel(app.UIAxes2, 'Intensity (Vrms)')
            zlabel(app.UIAxes2, 'Z')
            app.StartWLumEditFieldLabel = uilabel(app.StepTab);
            app.StartWLumEditFieldLabel.Text = 'Start WL (um)';
            app.StartWLumEditField = uieditfield(app.StepTab, 'numeric');
            app.StartWLumEditField.Value = 5.5;
            app.StopWLumEditFieldLabel = uilabel(app.StepTab);
            app.StopWLumEditFieldLabel.Text = 'Stop WL (um)';
            app.StopWLumEditField = uieditfield(app.StepTab, 'numeric');
            app.StopWLumEditField.Value = 7.5;
            app.InttimesEditFieldLabel = uilabel(app.StepTab);
            app.InttimesEditFieldLabel.Text = 'Int. time (s)';
            app.InttimesEditField = uieditfield(app.StepTab, 'numeric');
            app.InttimesEditField.Value = 0.1;
            app.RecordspectrumButton = uibutton(app.StepTab, 'push');
            app.RecordspectrumButton.ButtonPushedFcn = createCallbackFcn(app, @RecordspectrumButtonPushed, true);
            app.RecordspectrumButton.Text = 'Record spectrum';
            app.WLstepsizeumEditFieldLabel = uilabel(app.StepTab);
            app.WLstepsizeumEditFieldLabel.Text = 'WL step size (um)';
            app.WLstepsizeumEditField = uieditfield(app.StepTab, 'numeric');
            app.WLstepsizeumEditField.Value = 0.01;
            app.SweepTab = uitab(app.TabGroup);
            app.SweepTab.Title = 'Sweep';
            app.TextAreaLabel = uilabel(app.SweepTab);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.SweepTab);
            app.TextArea.Value = {'Note to self: the Mircat has an external step trigger. This could potentially solve the issue of timing the wavelength with DAQ.'};
            app.LasersettingsTab = uitab(app.TabGroup);
            app.LasersettingsTab.Title = 'Laser settings';
            app.LaserparametersPanel = uipanel(app.LasersettingsTab);
            app.LaserparametersPanel.Title = 'Laser parameters';
            app.PulserateHzEditFieldLabel = uilabel(app.LaserparametersPanel);
            app.PulserateHzEditFieldLabel.Text = 'Pulse rate (Hz)';
            app.PulserateHzEditField = uieditfield(app.LaserparametersPanel, 'numeric');
            app.PulserateHzEditField.Limits = [0 1000000000];
            app.PulserateHzEditField.ValueChangedFcn = createCallbackFcn(app, @PulserateHzEditFieldValueChanged, true);
            app.PulserateHzEditField.Value = 100000;
            app.DutyCycleEditFieldLabel = uilabel(app.LaserparametersPanel);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle (%)';
            app.DutyCycleEditField = uieditfield(app.LaserparametersPanel, 'numeric');
            app.DutyCycleEditField.ValueChangedFcn = createCallbackFcn(app, @DutyCycleEditFieldValueChanged, true);
            app.DutyCycleEditField.Value = 1;
            app.QCL1Label = uilabel(app.LaserparametersPanel);
            app.QCL1Label.Text = 'QCL1';
            app.QCL2Label = uilabel(app.LaserparametersPanel);
            app.QCL2Label.Text = 'QCL2';
            app.PulserateHzEditField_2Label = uilabel(app.LaserparametersPanel);
            app.PulserateHzEditField_2Label.Text = 'Pulse rate (Hz)';
            app.PulserateHzEditField_2 = uieditfield(app.LaserparametersPanel, 'numeric');
            app.PulserateHzEditField_2.Limits = [0 1000000000];
            app.PulserateHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @PulserateHzEditField_2ValueChanged, true);
            app.PulserateHzEditField_2.Value = 100000;
            app.DutyCycleEditField_2Label = uilabel(app.LaserparametersPanel);
            app.DutyCycleEditField_2Label.Text = 'Duty Cycle (%)';
            app.DutyCycleEditField_2 = uieditfield(app.LaserparametersPanel, 'numeric');
            app.DutyCycleEditField_2.ValueChangedFcn = createCallbackFcn(app, @DutyCycleEditField_2ValueChanged, true);
            app.PulsewidthnsEditFieldLabel = uilabel(app.LaserparametersPanel);
            app.PulsewidthnsEditFieldLabel.Text = 'Pulse width (ns)';
            app.PulsewidthnsEditField = uieditfield(app.LaserparametersPanel, 'numeric');
            app.PulsewidthnsEditField.ValueChangedFcn = createCallbackFcn(app, @PulsewidthnsEditFieldValueChanged, true);
            app.PulsewidthnsEditField.Value = 100;
            app.PulsewidthnsEditField_2Label = uilabel(app.LaserparametersPanel);
            app.PulsewidthnsEditField_2Label.Text = 'Pulse width (ns)';
            app.PulsewidthnsEditField_2 = uieditfield(app.LaserparametersPanel, 'numeric');
            app.PulsewidthnsEditField_2.ValueChangedFcn = createCallbackFcn(app, @PulsewidthnsTextAreaValueChanged, true);
            app.SDKfolderEditFieldLabel = uilabel(app.LasersettingsTab);
            app.SDKfolderEditFieldLabel.Text = 'SDK folder';
            app.SDKfolderEditField = uieditfield(app.LasersettingsTab, 'text');
            app.SDKfolderEditField.ValueChangedFcn = createCallbackFcn(app, @SDKfolderEditFieldValueChanged, true);
            app.SDKfolderEditField.Value = 'C:\Users\mathieu\work\people\Koen\mircat QCL laser\SDK\';
            app.DAQsettingsTab = uitab(app.TabGroup);
            app.DAQsettingsTab.Title = 'DAQ settings';
            app.DAQchannelsettingsPanel = uipanel(app.DAQsettingsTab);
            app.DAQchannelsettingsPanel.Title = 'DAQ channel settings';
            app.Ch0rangeVEditFieldLabel = uilabel(app.DAQchannelsettingsPanel);
            app.Ch0rangeVEditFieldLabel.Text = 'Ch.0 range (V)';
            app.Ch0rangeVEditField = uieditfield(app.DAQchannelsettingsPanel, 'numeric');
            app.Ch0rangeVEditField.ValueChangedFcn = createCallbackFcn(app, @Ch0rangeVEditFieldValueChanged, true);
            app.Ch0rangeVEditField.Value = 6;
            app.nSamplesEditFieldLabel = uilabel(app.DAQchannelsettingsPanel);
            app.nSamplesEditFieldLabel.Text = 'nSamples';
            app.nSamplesEditField = uieditfield(app.DAQchannelsettingsPanel, 'numeric');
            app.nSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @nSamplesEditFieldValueChanged, true);
            app.nSamplesEditField.Value = 8000;
            app.SamplefreqHzEditFieldLabel = uilabel(app.DAQchannelsettingsPanel);
            app.SamplefreqHzEditFieldLabel.Text = 'Sample freq. (Hz)';
            app.SamplefreqHzEditField = uieditfield(app.DAQchannelsettingsPanel, 'numeric');
            app.SamplefreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplefreqHzEditFieldValueChanged, true);
            app.SamplefreqHzEditField.Value = 100000000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QCLSpectroMeter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end